classdef kinematic_manipulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        SettingsPanel        matlab.ui.container.Panel
        ListBox              matlab.ui.control.ListBox
        varphi_3Slider       matlab.ui.control.Slider
        varphi_3SliderLabel  matlab.ui.control.Label
        varphi_2Slider       matlab.ui.control.Slider
        fi2Label             matlab.ui.control.Label
        varphi_1Slider       matlab.ui.control.Slider
        varphi_1Label        matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        manipulator
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Creating manipulator objeckt
            app.manipulator = Manipulator(300, 300, 200);
            UIAxesButtonDown(app)
            UIAxes2ButtonDown(app)
            UIAxes3ButtonDown(app)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Reading Slider Values
            fi1 = app.varphi_1Slider.Value;
            fi2 = app.varphi_2Slider.Value;
            fi3 = app.varphi_3Slider.Value;
               
            % Calculate end effector position
            [Xc0, Yc0, Zc0] = app.manipulator.calculateEndEffectorPosition(-fi1, fi2, fi3);
            % Calculate segments position
            [P, A, B, C] = app.manipulator.calculateRoboticSegmentPosition( -fi1, fi2, fi3);


            % Clear previous plot
            cla(app.UIAxes);
    
            % Displaying End Effector Position
            plot3(app.UIAxes, 0, 0, 0, 'o', 'MarkerSize', 10, 'MarkerFaceColor', 'black');
            hold(app.UIAxes, 'on'); 
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');
            grid(app.UIAxes, 'on');
        
            % Display Vector
            quiver3(app.UIAxes, 0, 0, 0, Xc0, Yc0, Zc0,'black');
            plot3(app.UIAxes, Xc0, Yc0, Zc0, 'o', 'MarkerSize', 4, 'MarkerFaceColor', 'black');
            text(app.UIAxes, Xc0+10, Yc0+10, Zc0+50, 'C', 'FontSize', 20,'LineWidth',2, 'HorizontalAlignment', 'left');

            % Display first segment
            plot3(app.UIAxes, [P(1) A(1)], [P(2) A(2)], [P(3)  A(3)],'Color', [1, 0.5, 0],'LineWidth',6);
            plot3(app.UIAxes, A(1), A(2), A(3), 'o', 'MarkerSize', 8, 'MarkerFaceColor', 'black');
            text(app.UIAxes, A(1)+10, A(2)+10, A(3)+50, 'A', 'FontSize', 20,'LineWidth',2, 'HorizontalAlignment', 'left');

            % Display second segment
            plot3(app.UIAxes, [A(1) A(1)+B(1)], [A(2) B(2)+A(2)], [A(3) B(3)+A(3)],'Color', [1, 0.5, 0],'LineWidth',4);
            plot3(app.UIAxes, A(1)+B(1), A(2)+B(2), A(3)+B(3), 'o', 'MarkerSize', 6, 'MarkerFaceColor', 'black');
            text(app.UIAxes, A(1)+B(1)+10, A(2)+B(2)+10, A(3)+B(3)+50, 'B', 'FontSize', 20,'LineWidth',2, 'HorizontalAlignment', 'left');

            % Display third segment
            plot3(app.UIAxes, [A(1)+B(1) A(1)+B(1)+C(1)], [A(2)+B(2) A(2)+B(2)+C(2)], [A(3)+B(3) A(3)+B(3)+C(3)],'Color', [1, 0.5, 0],'LineWidth',2);
            
        end

        % Value changed function: varphi_1Slider
        function varphi_1SliderValueChanged(app, event)
            UIAxesButtonDown(app, event)
        end

        % Value changed function: varphi_2Slider
        function varphi_2SliderValueChanged(app, event)
            UIAxesButtonDown(app, event)
        end

        % Value changed function: varphi_3Slider
        function varphi_3SliderValueChanged(app, event)
            UIAxesButtonDown(app, event)
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            fi1 = 0;
            fi2 = -50; % Starting position for YZ trajectory
            fi3 = -10;
            size = 30+180+60+180+90-40-20;
            
            % Define arrays to store end effector positions
            X = zeros(size, 1);
            Y = zeros(size, 1);
            Z = zeros(size, 1);
            
            % Calculate end effector trajectory
            for i = 1:size
                if fi3 < 0 && fi2 == -50
                    fi3 = fi3 + 1;
                elseif fi3 == 0 && fi2 >= -50 && fi2 < 130
                    fi2 = fi2 + 1;
                elseif fi2 == 130 && fi3 >= 0 && fi3 < 60
                    fi3 = fi3 + 1;
                elseif fi3 == 60 && fi2 <= 130 && fi2 > -50
                    fi2 = fi2 - 1;
                else
                    fi3 = fi3 - 1;
                end
                
                % Calculate end effector position
                [X(i), Y(i), Z(i)] = app.manipulator.calculateEndEffectorPosition(-fi1, fi2, fi3);
            end
            
            % Plot the end effector trajectory
            plot(app.UIAxes2, Y, Z,'black');
            xlabel(app.UIAxes2, 'Y');
            ylabel(app.UIAxes2, 'Z');
            grid(app.UIAxes2, 'on');

            % Fill the plotted area
            fill(Y, Z, [0.8 0.8 0.8], 'Parent', app.UIAxes2);

        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            fi1 = -160;
            fi2 = 90; % Starting position for XY trajectory
            fi3 = 0;
            size = 160*2;
            
            % Define arrays to store end effector positions
            X = zeros(size, 1);
            Y = zeros(size, 1);
            Z = zeros(size, 1);
            
            % Calculate end effector trajectory
            for i = 1:size
                fi1 = fi1 + 1;
                
                % Calculate end effector position
                [X(i), Y(i), Z(i)] = app.manipulator.calculateEndEffectorPosition(-fi1, fi2, fi3);
            end

            fi1 = -20;
            fi2 = -50; % Starting position for XY trajectory
            fi3 = -30;
            size1 = 40;
            X1 = zeros(size1, 1);
            Y1 = zeros(size1, 1);
            Z1 = zeros(size1, 1);

            for i = 1:size1
                fi1 = fi1 + 1;

                % Calculate end effector position
                [X1(i), Y1(i), Z1(i)] = app.manipulator.calculateEndEffectorPosition(-fi1, fi2, fi3);
            end

            
            % Plot the end effector trajectory
            plot(app.UIAxes3, Y, X,'black');
            hold(app.UIAxes3, 'on');            
            plot(app.UIAxes3, Y1, X1,'black');
            plot(app.UIAxes3, [Y(1) Y1(size1)], [X(1) X1(size1)],'black');
            plot(app.UIAxes3, [Y(size) Y1(1)], [X(size) X1(1)],'black');
            xlabel(app.UIAxes3, 'Y');
            ylabel(app.UIAxes3, 'X');
            grid(app.UIAxes3, 'on');

            % Fill the plotted area
            fill([Y; Y1; Y(1)], [X; X1; X(1)], [0.8 0.8 0.8], 'Parent', app.UIAxes3);

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value; value = app.ListBox.Value;
            
            % Reading Slider Values
            fi1 = app.varphi_1Slider.Value;
            fi2 = app.varphi_2Slider.Value;
            fi3 = app.varphi_3Slider.Value;


            if strcmp(value, 'Axis0') 
                [X, Y, Z, P] = app.manipulator.axer_0();
            elseif strcmp(value, 'Axis1') 
                [X, Y, Z, P] = app.manipulator.axer_1(fi1);
            elseif strcmp(value, 'Axis2') 
                [X, Y, Z, P] = app.manipulator.axer_2(fi1);
            elseif strcmp(value, 'Axis3') 
                [X, Y, Z, P] = app.manipulator.axer_3(fi1,-fi2);
            elseif strcmp(value, 'Axis4') 
                [X, Y, Z, P] = app.manipulator.axer_4(fi1,-fi2);
            elseif strcmp(value, 'Axis5') 
                [X, Y, Z, P] = app.manipulator.axer_5(fi1,-fi2,-fi3);
            elseif strcmp(value, 'Axis6') 
                [X, Y, Z, P] = app.manipulator.axer_6(fi1,-fi2,-fi3);
            end
            UIAxesButtonDown(app, event)
            % Plot the vectors
            quiver3(app.UIAxes, P(1), P(2), P(3), X(1), X(2), X(3), 'r');
            % hold(app.UIAxes, 'on');
            quiver3(app.UIAxes, P(1), P(2), P(3), Y(1), Y(2), Y(3), 'g');
            quiver3(app.UIAxes, P(1), P(2), P(3), Z(1), Z(2), Z(3), 'b');    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'YZ Workspace')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'XY Workspace')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Robotic Arm')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.ZLim = [-100 800];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SettingsPanel = uipanel(app.Panel);
            app.SettingsPanel.Title = 'Settings';
            app.varphi_1Label = uilabel(app.SettingsPanel);
            app.varphi_1Label.Interpreter = 'latex';
            app.varphi_1Label.Text = '$\varphi_1$ [°]';
            app.varphi_1Slider = uislider(app.SettingsPanel);
            app.varphi_1Slider.Limits = [-160 160];
            app.varphi_1Slider.ValueChangedFcn = createCallbackFcn(app, @varphi_1SliderValueChanged, true);
            app.varphi_1Slider.Value = -30;
            app.fi2Label = uilabel(app.SettingsPanel);
            app.fi2Label.Interpreter = 'latex';
            app.fi2Label.Text = '$\varphi_2$ [°]';
            app.varphi_2Slider = uislider(app.SettingsPanel);
            app.varphi_2Slider.Limits = [-50 130];
            app.varphi_2Slider.MajorTicks = [-50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100 110 120 130];
            app.varphi_2Slider.ValueChangedFcn = createCallbackFcn(app, @varphi_2SliderValueChanged, true);
            app.varphi_2Slider.Value = 30;
            app.varphi_3SliderLabel = uilabel(app.SettingsPanel);
            app.varphi_3SliderLabel.Interpreter = 'latex';
            app.varphi_3SliderLabel.Text = '$\varphi_3$ [°]';
            app.varphi_3Slider = uislider(app.SettingsPanel);
            app.varphi_3Slider.Limits = [-30 60];
            app.varphi_3Slider.MajorTicks = [-30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60];
            app.varphi_3Slider.ValueChangedFcn = createCallbackFcn(app, @varphi_3SliderValueChanged, true);
            app.varphi_3Slider.Value = 30;
            app.ListBox = uilistbox(app.SettingsPanel);
            app.ListBox.Items = {'Axis0', 'Axis1', 'Axis2', 'Axis3', 'Axis4', 'Axis5', 'Axis6'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Axis0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kinematic_manipulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end